classdef calligraphyTools < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CalligraphToolsUIFigure       matlab.ui.Figure
        UI1                           matlab.ui.control.UIAxes
        LoadImgButton                 matlab.ui.control.Button
        NonliearFilterButton          matlab.ui.control.Button
        ReverseButton                 matlab.ui.control.Button
        FillHolesButton               matlab.ui.control.Button
        SaveResultButton              matlab.ui.control.Button
        AngleLabel                    matlab.ui.control.Label
        AngleEditField                matlab.ui.control.NumericEditField
        DetectLinesButton             matlab.ui.control.Button
        MaxDetectLinesEditFieldLabel  matlab.ui.control.Label
        MaxDetectLinesEditField       matlab.ui.control.NumericEditField
        NMSEditFieldLabel             matlab.ui.control.Label
        NMSEditField                  matlab.ui.control.NumericEditField
        FindContourButton             matlab.ui.control.Button
        AddlineButton                 matlab.ui.control.Button
        DeleteObjButton               matlab.ui.control.Button
        XEditFieldLabel               matlab.ui.control.Label
        XEditField                    matlab.ui.control.NumericEditField
        YEditFieldLabel               matlab.ui.control.Label
        YEditField                    matlab.ui.control.NumericEditField
        AddEllipseButton              matlab.ui.control.Button
        PutCirclesButton              matlab.ui.control.Button
        DetectHengButton              matlab.ui.control.Button
        DetectShuButton               matlab.ui.control.Button
        DisplayContourSwitchLabel     matlab.ui.control.Label
        DisplayContourSwitch          matlab.ui.control.Switch
        DisplayCircleLineSwitchLabel  matlab.ui.control.Label
        DisplayCircleLineSwitch       matlab.ui.control.Switch
        ClearAllButton                matlab.ui.control.Button
        DeleteAreaButton              matlab.ui.control.Button
    end


    properties (Access = public)
        oriImgbw % Description
        curImgbw
        detectAngle
        MaxDetectLineNumbers
        NMSpercent
        Lines
        drawType
        lpress
        dragMode
        contourInx
        contourX
        contourY
        rects
        mask
        deleteFlag
        delRects
    end

    methods (Access = public)
    
        function detectLines(app)
            lines = [];
          %将字分为各个连通域，检测每个连通域内的直线
            [connectLabel,cntLabel] = bwlabel(app.curImgbw,8);    
            
            for i = 1:cntLabel
                tmpImgBw = false(size(app.curImgbw));
                [x,y]=find(connectLabel==i);
                for j = 1:length(x)
                    tmpImgBw(x(j),y(j)) = 1 ;
                end
%                  figure(3),imshow(tmpImgBw);
              %-----------------得到参数空间------------------
                [H, theta, rho]= hough(tmpImgBw,'RhoResolution', 0.5,'ThetaResolution',0.5);
                % peak = houghpeaks(H, 10);
                copyH = zeros(size(H)); 
                if app.detectAngle < -85 
                    copyH(1:end,1: app.detectAngle*2+180+10) = H(:,1: app.detectAngle*2+180+10);
                    copyH(1:end,end-(10-(app.detectAngle*2+180)):end) = H(:,end-(10-(app.detectAngle*2+180)):end);
                elseif app.detectAngle > 85
                    copyH(1:end,app.detectAngle*2+180-10: end) = H(:,app.detectAngle*2+180-10: end);
                    copyH(1:end,1: (360-(app.detectAngle*2+180+10))) = H(:,1: (360-(app.detectAngle*2+180+10)));
                else
                    copyH(1:end,app.detectAngle*2+180-10: app.detectAngle*2+180+10) = H(:,app.detectAngle*2+180-10: app.detectAngle*2+180+10);
                end
                
                peak = houghpeaks(copyH, app.MaxDetectLineNumbers,'NHoodSize',2*floor(ceil(size(copyH).*app.NMSpercent/100)/2)+1);
                
                lines = [lines,houghlines(tmpImgBw,theta,rho,peak)]; %返回原图直线信息

            end
            
            for j = 1:length(lines)
                lines(j).color = [];
                lines(j).area = [];
            end
            app.Lines = [app.Lines,lines];
        end
        
        
        function oPoint = changeCoordination(app)
            
            point = app.CalligraphToolsUIFigure.CurrentPoint;
            
            %通过尝试，计算得出，左上角的点是不发生变化的，再根据图片的大小
            %计算缩放比例
            oPoint = [(point(1)-110)*2.23,(350-point(2))*2.23];
        end
        
        
        function updateDisplay(app)          
            imshow(app.curImgbw,'Parent',app.UI1);
            hold(app.UI1,'on');
            if strcmp(app.DisplayContourSwitch.Value , 'On')
                plot(app.UI1,app.contourY(app.contourInx),app.contourX(app.contourInx),'r','LineWidth',1);
            end
            if strcmp(app.DisplayCircleLineSwitch.Value , 'On')
                for k=1:length(app.Lines)
                    xy=[app.Lines(k).point1;app.Lines(k).point2];
                    if isempty(app.Lines(k).color)            
                        if app.Lines(k).theta > -5 &&  app.Lines(k).theta < 5
                            plot(app.UI1,xy(:,1),xy(:,2),'LineWidth',2,'Color','m');
                        elseif app.Lines(k).theta < -85 ||  app.Lines(k).theta > 85
                            plot(app.UI1,xy(:,1),xy(:,2),'LineWidth',2,'Color','b');
                        else
                            plot(app.UI1,xy(:,1),xy(:,2),'LineWidth',2,'Color','g');
                        end
                    else
                        plot(app.UI1,xy(:,1),xy(:,2),'LineWidth',2,'Color','r');
                    end
                        
                end  
            end
            
        end
        
        function drawEllipse(app)
            if strcmp(app.DisplayCircleLineSwitch.Value , 'On')
                for i = 1:length(app.rects)
                    x0 = (app.rects(i).point1(1) + app.rects(i).point2(1))/2;
                    y0 = (app.rects(i).point1(2) + app.rects(i).point2(2))/2;
                    r1 = abs(app.rects(i).point1(1) - app.rects(i).point2(1))/2;
                    r2 = abs(app.rects(i).point1(2) - app.rects(i).point2(2))/2;
                    t = linspace(0,2*pi);
                    x = x0 + r1*cos(t);
                    y = y0 + r2*sin(t);
                    if isempty(app.rects(i).color)               
                        plot(app.UI1,x,y,'LineWidth',2,'Color','c');
                    else
                        plot(app.UI1,x,y,'LineWidth',2,'Color','r');
                    end
                end
            end
        end
        
        function dragEllipse(app)
            if strcmp(app.DisplayCircleLineSwitch.Value , 'On')
                for i = 1:length(app.rects)-1
                    x0 = (app.rects(i).point1(1) + app.rects(i).point2(1))/2;
                    y0 = (app.rects(i).point1(2) + app.rects(i).point2(2))/2;
                    r1 = abs(app.rects(i).point1(1) - app.rects(i).point2(1))/2;
                    r2 = abs(app.rects(i).point1(2) - app.rects(i).point2(2))/2;
                    t = linspace(0,2*pi);
                    x = x0 + r1*cos(t);
                    y = y0 + r2*sin(t);
                    plot(app.UI1,x,y,'LineWidth',2,'Color','c');
                end
                point = changeCoordination(app);
                x0 = point(1);
                y0 = point(2);
                r1 = abs(app.rects(end).point1(1) - app.rects(end).point2(1))/2;
                r2 = abs(app.rects(end).point1(2) - app.rects(end).point2(2))/2;
                x0_0 = (app.rects(end).point1(1) + app.rects(end).point2(1))/2;
                y0_0 = (app.rects(end).point1(2) + app.rects(end).point2(2))/2;
                app.rects(end).point1(1) = app.rects(end).point1(1) + (x0-x0_0);
                app.rects(end).point1(2) = app.rects(end).point1(2) + (y0-y0_0);
                app.rects(end).point2(1) = app.rects(end).point2(1) + (x0-x0_0);
                app.rects(end).point2(2) = app.rects(end).point2(2) + (y0-y0_0);
                t = linspace(0,2*pi);
                x = x0 + r1*cos(t);
                y = y0 + r2*sin(t);
                plot(app.UI1,x,y,'LineWidth',2,'Color','c');
            end
        end
        
        function [center,r] = findMaxInnerCircle(~,contour,BW)
            center = [0,0];
            r = 0;
            [x,y] = find(contour==true);
            [x1,y1] = find(xor(contour , BW)==true);
            for i = 1:length(x1)
                minDist = inf;
                for j = 1:length(x)
                    dis = sqrt((x(j)-x1(i))^2+(y(j)-y1(i))^2);
                    if dis < minDist
                        minDist = dis;
                    end
                end
                if minDist > r   
                    r = minDist;
                    center = [x1(i),y1(i)];
                end
            end
        end
        
        function [foot,dis] = orig2Line(~,point1,point2)
            x = (point1(2)-point2(2))*(point1(2)*point2(1)-point2(2)*point1(1)) / ( ( point2(1) - point1(1))^2 + ( point2(2) - point1(2) )^2 );
            y = (point2(1)-point1(1))*(point1(2)*point2(1)-point2(2)*point1(1)) / ( ( point2(1) - point1(1))^2 + ( point2(2) - point1(2) )^2 );
            foot = [x,y];
            dis = sqrt( x^2 + y^2 );
        end
        
        function drawDelArea(app)
            point0 = app.delRects(end).point1;
            point1 = app.delRects(end).point1 + [0, app.delRects(end).point2(2) - app.delRects(end).point1(2)];
            point2 = app.delRects(end).point2;
            point3 = app.delRects(end).point1 + [app.delRects(end).point2(1) - app.delRects(end).point1(1),0];
            x = [point0(1),point1(1),point2(1),point3(1),point0(1)];
            y = [point0(2),point1(2),point2(2),point3(2),point0(2)];
            plot(app.UI1,x,y,'LineWidth',2,'Color','r');           
        end
        
        function dragDelArea(app)
            point = changeCoordination(app);
            detX = app.delRects(end).point2(1) - app.delRects(end).point1(1);
            detY = app.delRects(end).point2(2) - app.delRects(end).point1(2);
            point0 = point - [detX/2,detY/2];
            point1 = point0 + [0,detY];
            point2 = point0 + [detX,detY];
            point3 = point0 + [detX,0]; 
            app.delRects.point1 = point0;
            app.delRects.point2 = point2;
            x = [point0(1),point1(1),point2(1),point3(1),point0(1)];
            y = [point0(2),point1(2),point2(2),point3(2),point0(2)];
            plot(app.UI1,x,y,'LineWidth',2,'Color','r');
        end
        
        function getObjInArea(app)
    
            for i = 1:length(app.Lines)
                if ~isempty(app.Lines(i).area)
                    if ~isempty(find(app.Lines(i).area & app.delRects.area ,1))
                        app.Lines(i).color = 'r';  
                    else
                        app.Lines(i).color = [];
                    end
                end
            end
            for i = 1:length(app.rects)
%                 figure(1),imshow(app.rects(i).area);
%                 figure(2),imshow(app.delRects.area);
                if ~isempty(app.rects(i).area)
                    if ~isempty(find(app.rects(i).area & app.delRects.area ,1))
                        app.rects(i).color = 'r'; 
                    else
                        app.rects(i).color = [];
                    end
                end
            end            
        end
        
        function addLineRectsArea(app)
            for i = 1:length(app.Lines)
                tmpMask = false(size(app.curImgbw));
                if app.Lines(i).point1(1) == app.Lines(i).point2(1) && app.Lines(i).point1(2) == app.Lines(i).point2(2)
                    tmpMask(app.Lines(i).point1(2),app.Lines(i).point1(1)) =  true;   
                elseif app.Lines(i).point1(1) == app.Lines(i).point2(1) && app.Lines(i).point1(2) ~= app.Lines(i).point2(2) 
                    tmpMask(app.Lines(i).point1(2):app.Lines(i).point2(2),app.Lines(i).point1(1)) =  true;
                elseif app.Lines(i).point1(1) ~= app.Lines(i).point2(1) && app.Lines(i).point1(2) == app.Lines(i).point2(2) 
                    tmpMask(app.Lines(i).point1(2),app.Lines(i).point1(1):app.Lines(i).point2(1)) =  true;
                else
                    %get general line
                    k = (app.Lines(i).point2(2) - app.Lines(i).point1(2)) / (app.Lines(i).point2(1) - app.Lines(i).point1(1));
                    b = app.Lines(i).point1(2) - app.Lines(i).point1(1)*k;
                    for x_ = app.Lines(i).point1(1) : app.Lines(i).point2(1)
                        y_ = k*x_ + b;
                        tmpMask(y_,x_) = true;
                    end
                end
                app.Lines(i).area = tmpMask;
            end
            for i = 1:length(app.rects)
                point0 = app.rects(i).point1;
                point1 = app.rects(i).point1 + [0, app.rects(i).point2(2) - app.rects(i).point1(2)];
                point2 = app.rects(i).point2;
                point3 = app.rects(i).point1 + [app.rects(i).point2(1) - app.rects(i).point1(1),0];
                x = [point0(1),point1(1),point2(1),point3(1)];
                y = [point0(2),point1(2),point2(2),point3(2)];
                tmpMask = false(size(app.curImgbw));
     
                % change method faster
                tmpMask = roipoly(tmpMask,x,y);
                
                app.rects(i).area = tmpMask;
            end
            
            %delete area
            point0 = app.delRects(end).point1;
            point1 = app.delRects(end).point1 + [0, app.delRects(end).point2(2) - app.delRects(end).point1(2)];
            point2 = app.delRects(end).point2;
            point3 = app.delRects(end).point1 + [app.delRects(end).point2(1) - app.delRects(end).point1(1),0];
            x = [point0(1),point1(1),point2(1),point3(1)];
            y = [point0(2),point1(2),point2(2),point3(2)];
            tmpMask = false(size(app.curImgbw));
 
            % change method faster
            tmpMask = roipoly(tmpMask,x,y);
            
            app.delRects(end).area = tmpMask;
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MaxDetectLinesEditField.Value = 5;  %default value of max detect lines
            app.MaxDetectLineNumbers = 5;
            
            app.NMSEditField.Value = 10;
            app.NMSpercent = 10;
            
            app.detectAngle = -90;
            app.AngleEditField.Value = 0;
         
            app.drawType = 0;    %0 represent draw nothing

            app.lpress = false;
            app.dragMode = false;
            
            app.Lines = [];
            app.mask = [];
            app.delRects = [];
            app.rects = [];
            
            app.DisplayContourSwitch.Value = 'On';
            app.DisplayCircleLineSwitch.Value = 'On';
            
            app.deleteFlag = 0;
            %helpdlg('Hello','Help');
            
        end

        % Button pushed function: LoadImgButton
        function LoadImgButtonPushed(app, event)
            app.drawType = 0;    %draw a line
            app.deleteFlag = 0;
            [pwd,~,~] = fileparts(mfilename('fullpath')); 
            oldPath = pwd;
            cd([pwd, '\data\kaishu\']);

            [file,path] = uigetfile({'*.png';'*.jpg'});
            cd(oldPath);
            if isequal(file,0)
               errordlg('Error select','Error')
            else
                hold(app.UI1,'off');
                fig = imread(fullfile(path,file));
                if size(fig,1) > 600 || size(fig,2) > 800
                   fig = imresize(fig,[600,800]); 
                end
                img_gray = rgb2gray(fig);
                app.oriImgbw = imbinarize(img_gray,graythresh(img_gray));
                app.curImgbw = app.oriImgbw;   
                
                imshow(app.oriImgbw,'Parent',app.UI1);
                app.UI1.OuterPosition = [0,0,400,300];
                app.UI1.XLim = [0,800];
                app.UI1.YLim = [0,600];
                app.UI1.Position = [80,60,400,300];
                hold(app.UI1,'on');          

            end
        end

        % Button pushed function: NonliearFilterButton
        function NonliearFilterButtonPushed(app, event)
            app.drawType = 0;    %draw a line
            app.deleteFlag = 0;
            [connectLabel,cntLabel] = bwlabel(app.curImgbw,8);
            imgCL = false(size(connectLabel));
            for i = 1:cntLabel
                [r,c]=find(connectLabel== i);
                if length(r) > floor(size(imgCL,1)*size(imgCL,2)*0.002)  %提取所占面积大于1%的区域
                    for j = 1:length(r)
                        imgCL(r(j),c(j)) = 1;
                    end
                end
            end
            app.curImgbw = imgCL;
            imshow(app.curImgbw,'Parent',app.UI1);
        end

        % Button pushed function: ReverseButton
        function ReverseButtonPushed(app, event)
            app.drawType = 0;    %draw a line
            app.deleteFlag = 0;
            app.curImgbw = ~app.curImgbw;
            imshow(app.curImgbw,'Parent',app.UI1);
        end

        % Button pushed function: FillHolesButton
        function FillHolesButtonPushed(app, event)
            app.drawType = 0;    %draw a line
            app.deleteFlag = 0;
            imgbw = ~app.curImgbw;

            [connectLabel,cntLabel] = bwlabel(imgbw,8);
            % imgCL = false(size(connectLabel));
            for i = 1:cntLabel
                [r,c]=find(connectLabel== i);
                if length(r) < floor(size(app.curImgbw,1)*size(app.curImgbw,2)*0.002)  %提取所占面积大于1%的区域
                    for j = 1:length(r)
                        app.curImgbw(r(j),c(j)) = 1;
                    end
                end
            end
            imshow(app.curImgbw,'Parent',app.UI1);
        end

        % Button pushed function: SaveResultButton
        function SaveResultButtonPushed(app, event)
            app.drawType = 0;    %draw a line
            app.deleteFlag = 0;
            
            % Create a temporary figure with axes.
            fig = figure;
            fig.Visible = 'off';
            figAxes = axes(fig);
            % Copy all UIAxes children, take over axes limits and aspect ratio.            
            allChildren = app.UI1.XAxis.Parent.Children;
            copyobj(allChildren, figAxes)
            figAxes.XLim = app.UI1.XLim;
            figAxes.YLim = app.UI1.YLim;
            figAxes.ZLim = app.UI1.ZLim;
            figAxes.DataAspectRatio = app.UI1.DataAspectRatio;
            % Save as png and fig files.
            [file,path] = uiputfile('*.png','Save As');
      
            figAxes.XTick = [];
            figAxes.YTick = [];
            figAxes.YDir = 'reverse';
            saveas(fig, [path,file]);
      
            % Delete the temporary figure.
%             delete(fig);
           
   
        end

        % Value changed function: AngleEditField
        function AngleEditFieldValueChanged(app, event)
            
            value = app.AngleEditField.Value;
            if value < -90 || value >= 90
                errordlg('Value must in [-90,90)','Error');
            else
                app.detectAngle = floor(value);
                if app.detectAngle > 0
                    app.detectAngle = 90 - app.detectAngle;
                else
                    app.detectAngle = -(90 + app.detectAngle);
                end
            end
        end

        % Button pushed function: DetectLinesButton
        function DetectLinesButtonPushed(app, event)
            app.drawType = 0;    %draw a line
            app.deleteFlag = 0;
            detectLines(app);
            
            hold(app.UI1,'off');
            updateDisplay(app);
            drawEllipse(app);

        end

        % Value changed function: MaxDetectLinesEditField
        function MaxDetectLinesEditFieldValueChanged(app, event)
            value = app.MaxDetectLinesEditField.Value;
            if value < 1
                errordlg('Value must be int. and larger than 0','Error');
            else
                app.MaxDetectLineNumbers = floor(value);
            end
        end

        % Value changed function: NMSEditField
        function NMSEditFieldValueChanged(app, event)
            value = app.NMSEditField.Value;
            if value < 0 || value > 100
                errordlg('Value must in [0,100]','Error');
            else
                app.NMSpercent = value;
            end
        end

        % Button pushed function: FindContourButton
        function FindContourButtonPushed(app, event)
            app.drawType = 0;    %draw a line
            app.deleteFlag = 0;
            [app.contourX,app.contourY] = find(app.curImgbw==1);
            app.contourInx = convhull(app.contourX,app.contourY);
            
            hold(app.UI1,'off');
            updateDisplay(app);
            drawEllipse(app);
%             plot(app.UI1,app.contourY(app.contourInx),app.contourX(app.contourInx),'r','LineWidth',1);
            

        end

        % Button pushed function: AddlineButton
        function AddlineButtonPushed(app, event)
            app.drawType = 1;    %draw a line
            app.deleteFlag = 0;
        end

        % Window button motion function: CalligraphToolsUIFigure
        function MouseMoveEvent(app, event)
            point = app.CalligraphToolsUIFigure.CurrentPoint;
            app.XEditField.Value = point(1);
            app.YEditField.Value = point(2);

            if app.lpress
                newEndPoint = changeCoordination(app);
                if app.drawType == 1
                 
                    if newEndPoint(1) ~= app.Lines(end).point2(1) || newEndPoint(2) ~= app.Lines(end).point2(2)
                        app.Lines(end).point2 = newEndPoint;
                        %compute a line rho and theta      
                        [foot,dis] = orig2Line(app,app.Lines(end).point1,app.Lines(end).point2);
                        app.Lines(end).rho = dis;                  
                        app.Lines(end).theta = atan( foot(2) / foot(1) )*180/pi;
                        hold(app.UI1,'off');
                        updateDisplay(app);
                        drawEllipse(app);
                    end
                elseif app.drawType == 2 
               
                    if ~app.dragMode     
                        if newEndPoint(1) ~= app.rects(end).point2(1) || newEndPoint(2) ~= app.rects(end).point2(2)
                            app.rects(end).point2 = newEndPoint;
                            hold(app.UI1,'off');
                            updateDisplay(app);
                            drawEllipse(app);
                        end
                    end
                end
                if app.deleteFlag == 1
                    
                    if ~app.dragMode     
                        if newEndPoint(1) ~= app.delRects(end).point2(1) || newEndPoint(2) ~= app.delRects(end).point2(2)
                            app.delRects(end).point2 = newEndPoint;
                            hold(app.UI1,'off');
                            updateDisplay(app);
                            drawEllipse(app);
                            drawDelArea(app);
                        end
                    end
                elseif app.deleteFlag ==2
                    
                end
            else
                if app.drawType == 2 && app.dragMode
                    hold(app.UI1,'off');
                    updateDisplay(app);
                    dragEllipse(app);
                elseif app.deleteFlag ==1 && app.dragMode
                    hold(app.UI1,'off');
                    updateDisplay(app);
                    drawEllipse(app);
                    dragDelArea(app);
                elseif app.deleteFlag == 2
                    point = changeCoordination(app);
                    %a little selected area to detect obj to delete them
                    app.delRects.point1 = point - [20,20];
                    app.delRects.point2 = point + [20,20];
                    %delete area
                    point0 = app.delRects(end).point1;
                    point1 = app.delRects(end).point1 + [0, app.delRects(end).point2(2) - app.delRects(end).point1(2)];
                    point2 = app.delRects(end).point2;
                    point3 = app.delRects(end).point1 + [app.delRects(end).point2(1) - app.delRects(end).point1(1),0];
                    x = [point0(1),point1(1),point2(1),point3(1)];
                    y = [point0(2),point1(2),point2(2),point3(2)];
                    tmpMask = false(size(app.curImgbw));
         
                    % change method faster
                    tmpMask = roipoly(tmpMask,x,y);
                    
                    app.delRects(end).area = tmpMask;
                    getObjInArea(app);
                    hold(app.UI1,'off');
                    updateDisplay(app);
                    drawEllipse(app);
                    dragDelArea(app);
                end
                
            end
        end

        % Window button down function: CalligraphToolsUIFigure
        function MousePressEvent(app, event)
            app.lpress = true;
            point = changeCoordination(app);
            if app.drawType == 1       
                
                line.point1 = point;
                line.point2 = point;
                line.theta = 0;
                line.rho = 0;
                line.color = [];
                line.area = [];
                app.Lines = [app.Lines,line];
            elseif app.drawType == 2
                
                if ~app.dragMode                 
                    rect.point1 = point;
                    rect.point2 = point;
                    rect.color = [];
                    rect.area = [];
                    app.rects = [app.rects,rect];   
                end
            end
            if app.deleteFlag == 1  
                if ~app.dragMode                 
                    rect.point1 = point;
                    rect.point2 = point;
                    %delete area has default color 'red'
%                     rect.color = [];
                    app.delRects = rect;   
                end
                     
            end

        end

        % Window button up function: CalligraphToolsUIFigure
        function MouseReleaseEvent(app, event)
            if app.lpress
                if app.drawType == 1
                    app.Lines(end).point2 = changeCoordination(app);
                    %compute a line rho and theta      
                    [foot,dis] = orig2Line(app,app.Lines(end).point1,app.Lines(end).point2);
                    app.Lines(end).rho = dis;                  
                    app.Lines(end).theta = atan( foot(2) / foot(1) )*180/pi;
                    hold(app.UI1,'off');
                    updateDisplay(app);
                    drawEllipse(app);
                elseif app.drawType == 2                    
                    if ~app.dragMode  
                        app.rects(end).point2 = changeCoordination(app);
                        hold(app.UI1,'off');
                        updateDisplay(app);
                        drawEllipse(app);
                        app.dragMode = true; 
                    else
                        app.dragMode = false;
                        hold(app.UI1,'off');
                        updateDisplay(app);
                        dragEllipse(app);
                    end                  
                end
                if app.deleteFlag == 1
                    if ~app.dragMode
                        app.delRects(end).point2 = changeCoordination(app);
                        hold(app.UI1,'off');
                        updateDisplay(app);
                        drawEllipse(app);
                        drawDelArea(app);
                        app.dragMode = true;
                    else
                        app.dragMode = false;
                        hold(app.UI1,'off');
                        updateDisplay(app);
                        drawEllipse(app);
                        dragDelArea(app);
                        
                        %delete the area
                        app.curImgbw(floor(app.delRects.point1(2)): floor(app.delRects.point2(2)) , floor(app.delRects.point1(1)):floor(app.delRects.point2(1))) = 0;
                        hold(app.UI1,'off');
                        updateDisplay(app);
                        drawEllipse(app);
                    end
                elseif app.deleteFlag ==2
                    for i = length(app.Lines):-1:1
                        if strcmp(app.Lines(i).color,'r')
                            app.Lines(i) = [];
                        end
                    end
                    for i = length(app.rects):-1:1
                        if strcmp(app.rects(i).color,'r')
                            app.rects(i) = [];
                        end
                    end 
                    hold(app.UI1,'off');
                    updateDisplay(app);
                    drawEllipse(app);
                end
                app.lpress = false;
 
            end
        end

        % Button pushed function: AddEllipseButton
        function AddEllipseButtonPushed(app, event)
            app.drawType = 2;   %draw a ellipse
            app.deleteFlag = 0;
        end

        % Button pushed function: DeleteObjButton
        function DeleteObjButtonPushed(app, event)
            app.drawType = 0;
            app.deleteFlag = 2;
            point = changeCoordination(app);
            %a little selected area to detect obj to delete them
            rect.point1 = point - [20,20];
            rect.point2 = point + [20,20];
            app.delRects = rect;
            hold(app.UI1,'off');
            updateDisplay(app);
            drawEllipse(app);
            dragDelArea(app);
            
            %add line area and rects to line and rect area respectively
            addLineRectsArea(app);
            for i = 1:length(app.Lines)
                app.Lines(i).color = [];
            end
            for i = 1:length(app.rects)
                app.rects(i).color = [];
            end
        end

        % Button pushed function: PutCirclesButton
        function PutCirclesButtonPushed(app, event)
            app.drawType = 0;    %draw a line
            app.deleteFlag = 0;
            
            app.FindContourButton.ButtonPushedFcn(app,event);
            %找到凸包区域，凸包与背景相 与 得到小块区域,并将所有的区域划分为凸多边形
            tmpMask = false(size(app.curImgbw));
 
            % change method faster
            tmpMask = roipoly(tmpMask,app.contourY(app.contourInx),app.contourX(app.contourInx));
%             for i = 1:size(app.curImgbw,1)
%                 for j = 1:size(app.curImgbw,2)
%                     if app.contourY(app.contourInx),app.contourX(app.contourInx)
%                         tmpMask(i,j) = 1;            
%                     end
%                 end
%             end
%             figure(1),imshow(tmpMask);
            app.mask = tmpMask&(~app.curImgbw);
            if isempty(app.mask)
                errordlg('Find Contour must be executed first.','Error');
            else
                [connectLabel,cntLabel] = bwlabel(app.mask,8);
                for i = 1:cntLabel
                     % obtain the boundary
                    [x,y]=find(connectLabel==i);
                    if length(x)/(size(app.curImgbw,1)*size(app.curImgbw,2)) < 0.01
                        k = 0;
                        while connectLabel(x(1),y(1)) == connectLabel(x(1)-k,y(1)-k)
                            k = k - 1;
                        end
                        connectLabel(x(:),y(:)) = connectLabel(x(1)-k,y(1)-k);
                        continue;
                    end
                end
                for i = 1:cntLabel
                    tmpBW = false(size(app.curImgbw));
                    [x,y] = find(connectLabel==i);
                    for j = 1:length(x)
                        tmpBW(x(j), y(j)) = true;
                    end
                    contour = bwperim(tmpBW);
                    [center,r] = findMaxInnerCircle(app,contour,tmpBW);
                    rect.point1 = [center(2)-r,center(1)-r];
                    rect.point2 = [center(2)+r,center(1)+r];
                    rect.color = [];
                    rect.area = [];
                    app.rects = [app.rects,rect];  
                    hold(app.UI1,'off');
                    updateDisplay(app);
                    drawEllipse(app);
                end
            end
        end

        % Button pushed function: DetectHengButton
        function DetectHengButtonPushed(app, event)
            app.drawType = 0;    %draw a line
            app.deleteFlag = 0;
            app.detectAngle = -90;
            app.AngleEditField.Value = 0;
            %call detectLine callback function
            app.DetectLinesButton.ButtonPushedFcn(app,event);
        end

        % Button pushed function: DetectShuButton
        function DetectShuButtonPushed(app, event)
            app.drawType = 0;    %draw a line
            app.deleteFlag = 0;
            app.detectAngle = 0;
            app.AngleEditField.Value = 90;
            %call detectLine callback function
            app.DetectLinesButton.ButtonPushedFcn(app,event);            
        end

        % Value changed function: DisplayContourSwitch
        function DisplayContourSwitchValueChanged(app, event)
            hold(app.UI1,'off');
            updateDisplay(app);
            drawEllipse(app);         
        end

        % Value changed function: DisplayCircleLineSwitch
        function DisplayCircleLineSwitchValueChanged(app, event)
            hold(app.UI1,'off');
            updateDisplay(app);
            drawEllipse(app);         
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            app.drawType = 0;
            app.deleteFlag = 0;
            app.Lines = [];
            app.rects = [];
            app.contourX = [];
            app.contourInx = [];
            app.contourY = [];
            hold(app.UI1,'off');
            updateDisplay(app);
            drawEllipse(app);
        end

        % Button pushed function: DeleteAreaButton
        function DeleteAreaButtonPushed(app, event)
            
            app.drawType = 0;
            app.deleteFlag = 1;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CalligraphToolsUIFigure = uifigure;
            app.CalligraphToolsUIFigure.Name = 'CalligraphTools';
            app.CalligraphToolsUIFigure.WindowButtonDownFcn = createCallbackFcn(app, @MousePressEvent, true);
            app.CalligraphToolsUIFigure.WindowButtonUpFcn = createCallbackFcn(app, @MouseReleaseEvent, true);
            app.CalligraphToolsUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @MouseMoveEvent, true);
            app.UI1 = uiaxes(app.CalligraphToolsUIFigure);
            app.LoadImgButton = uibutton(app.CalligraphToolsUIFigure, 'push');
            app.LoadImgButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImgButtonPushed, true);
            app.LoadImgButton.Text = 'LoadImg';
            app.NonliearFilterButton = uibutton(app.CalligraphToolsUIFigure, 'push');
            app.NonliearFilterButton.ButtonPushedFcn = createCallbackFcn(app, @NonliearFilterButtonPushed, true);
            app.NonliearFilterButton.Text = 'NonliearFilter';
            app.ReverseButton = uibutton(app.CalligraphToolsUIFigure, 'push');
            app.ReverseButton.ButtonPushedFcn = createCallbackFcn(app, @ReverseButtonPushed, true);
            app.ReverseButton.Text = 'Reverse';
            app.FillHolesButton = uibutton(app.CalligraphToolsUIFigure, 'push');
            app.FillHolesButton.ButtonPushedFcn = createCallbackFcn(app, @FillHolesButtonPushed, true);
            app.FillHolesButton.Text = 'FillHoles';
            app.SaveResultButton = uibutton(app.CalligraphToolsUIFigure, 'push');
            app.SaveResultButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultButtonPushed, true);
            app.SaveResultButton.Text = 'SaveResult';
            app.AngleLabel = uilabel(app.CalligraphToolsUIFigure);
            app.AngleLabel.Text = 'Angle(°)';
            app.AngleEditField = uieditfield(app.CalligraphToolsUIFigure, 'numeric');
            app.AngleEditField.ValueChangedFcn = createCallbackFcn(app, @AngleEditFieldValueChanged, true);
            app.DetectLinesButton = uibutton(app.CalligraphToolsUIFigure, 'push');
            app.DetectLinesButton.ButtonPushedFcn = createCallbackFcn(app, @DetectLinesButtonPushed, true);
            app.DetectLinesButton.Text = 'DetectLines';
            app.MaxDetectLinesEditFieldLabel = uilabel(app.CalligraphToolsUIFigure);
            app.MaxDetectLinesEditFieldLabel.Text = 'MaxDetectLines';
            app.MaxDetectLinesEditField = uieditfield(app.CalligraphToolsUIFigure, 'numeric');
            app.MaxDetectLinesEditField.ValueChangedFcn = createCallbackFcn(app, @MaxDetectLinesEditFieldValueChanged, true);
            app.MaxDetectLinesEditField.Value = 5;
            app.NMSEditFieldLabel = uilabel(app.CalligraphToolsUIFigure);
            app.NMSEditFieldLabel.Text = 'NMS(%)';
            app.NMSEditField = uieditfield(app.CalligraphToolsUIFigure, 'numeric');
            app.NMSEditField.ValueChangedFcn = createCallbackFcn(app, @NMSEditFieldValueChanged, true);
            app.NMSEditField.Value = 80;
            app.FindContourButton = uibutton(app.CalligraphToolsUIFigure, 'push');
            app.FindContourButton.ButtonPushedFcn = createCallbackFcn(app, @FindContourButtonPushed, true);
            app.FindContourButton.Text = {'Find'; 'Contour'};
            app.AddlineButton = uibutton(app.CalligraphToolsUIFigure, 'push');
            app.AddlineButton.ButtonPushedFcn = createCallbackFcn(app, @AddlineButtonPushed, true);
            app.AddlineButton.Text = 'Addline';
            app.DeleteObjButton = uibutton(app.CalligraphToolsUIFigure, 'push');
            app.DeleteObjButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteObjButtonPushed, true);
            app.DeleteObjButton.Text = 'DeleteObj';
            app.XEditFieldLabel = uilabel(app.CalligraphToolsUIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.CalligraphToolsUIFigure, 'numeric');
            app.YEditFieldLabel = uilabel(app.CalligraphToolsUIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.CalligraphToolsUIFigure, 'numeric');
            app.AddEllipseButton = uibutton(app.CalligraphToolsUIFigure, 'push');
            app.AddEllipseButton.ButtonPushedFcn = createCallbackFcn(app, @AddEllipseButtonPushed, true);
            app.AddEllipseButton.Text = 'AddEllipse';
            app.PutCirclesButton = uibutton(app.CalligraphToolsUIFigure, 'push');
            app.PutCirclesButton.ButtonPushedFcn = createCallbackFcn(app, @PutCirclesButtonPushed, true);
            app.PutCirclesButton.Text = 'PutCircles';
            app.DetectHengButton = uibutton(app.CalligraphToolsUIFigure, 'push');
            app.DetectHengButton.ButtonPushedFcn = createCallbackFcn(app, @DetectHengButtonPushed, true);
            app.DetectHengButton.Text = 'DetectHeng';
            app.DetectShuButton = uibutton(app.CalligraphToolsUIFigure, 'push');
            app.DetectShuButton.ButtonPushedFcn = createCallbackFcn(app, @DetectShuButtonPushed, true);
            app.DetectShuButton.Text = 'DetectShu';
            app.DisplayContourSwitchLabel = uilabel(app.CalligraphToolsUIFigure);
            app.DisplayContourSwitchLabel.Text = 'DisplayContour';
            app.DisplayContourSwitch = uiswitch(app.CalligraphToolsUIFigure, 'slider');
            app.DisplayContourSwitch.ValueChangedFcn = createCallbackFcn(app, @DisplayContourSwitchValueChanged, true);
            app.DisplayContourSwitch.Value = 'On';
            app.DisplayCircleLineSwitchLabel = uilabel(app.CalligraphToolsUIFigure);
            app.DisplayCircleLineSwitchLabel.Text = 'DisplayCircleLine';
            app.DisplayCircleLineSwitch = uiswitch(app.CalligraphToolsUIFigure, 'slider');
            app.DisplayCircleLineSwitch.ValueChangedFcn = createCallbackFcn(app, @DisplayCircleLineSwitchValueChanged, true);
            app.DisplayCircleLineSwitch.Value = 'On';
            app.ClearAllButton = uibutton(app.CalligraphToolsUIFigure, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'ClearAll';
            app.DeleteAreaButton = uibutton(app.CalligraphToolsUIFigure, 'push');
            app.DeleteAreaButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteAreaButtonPushed, true);
            app.DeleteAreaButton.Text = 'DeleteArea';
        end
    end

    methods (Access = public)

        % Construct app
        function app = calligraphyTools

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CalligraphToolsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CalligraphToolsUIFigure)
        end
    end
end