classdef chThickness < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ResizeThicknessUIFigure  matlab.ui.Figure
        LoadImageButton          matlab.ui.control.Button
        ThickOrThinButton        matlab.ui.control.Button
        DropDownOritation        matlab.ui.control.DropDown
        ImgPanel                 matlab.ui.container.Panel
        UI1                      matlab.ui.control.UIAxes
        UI2                      matlab.ui.control.UIAxes
        Slider                   matlab.ui.control.Slider
        StepEditFieldLabel       matlab.ui.control.Label
        StepEditField            matlab.ui.control.NumericEditField
        SETButton                matlab.ui.control.Button
        ExportButton             matlab.ui.control.Button
    end


    properties (Access = public)
        originalImgbw % Description
        DisplaylImgbw
        imgbwNorSDF %Signed Distance Field 
        imgbwHorSDF
        imgbwVerSDF
        imgbwSDF
        setBtnPushTimes
        set1Value
        set2Value
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DropDownOritation.Value = 'Full';
            
            app.StepEditField.Value = 1;
            
            app.setBtnPushTimes = 0;
            
            app.set1Value = 0;
            app.set2Value = 0;
          %  app.Slider.Value = app.EditField.Value;
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)

            [pwd,~,~] = fileparts(mfilename('fullpath')); 
 
            cd([pwd,'/chinese/']);

            [file,path] = uigetfile({'*.png';'*.jpg'});
  %
            if isequal(file,0)
               errordlg('Error select','Error')
            else
                app.setBtnPushTimes = 0;
            
                app.set1Value = 0;
                app.set2Value = 0;
              % msgbox(fullfile(path,file),'Path')
                img_gray = rgb2gray(imread(fullfile(path,file)));
                app.originalImgbw = ~imbinarize(img_gray,graythresh(img_gray));
                imshow(~app.originalImgbw,'Parent',app.UI1);
                imshow(~app.originalImgbw,'Parent',app.UI2);
                [app.imgbwNorSDF,~] = computeDistanceField(app.originalImgbw);
                [app.imgbwHorSDF,~] = computeHorDistanceField(app.originalImgbw);
                [app.imgbwVerSDF,~] = computeVerDistanceField(app.originalImgbw);
            %    app.EditField.Value = cntNumOne(app.imgbw)/100.0;
            %    app.Slider.Value = app.EditField.Value;
                if strcmp(app.DropDownOritation.Value , 'Full')
                    app.imgbwSDF = app.imgbwNorSDF;
                elseif strcmp(app.DropDownOritation.Value , 'Horizontal')
                    app.imgbwSDF = app.imgbwHorSDF;
                elseif strcmp(app.DropDownOritation.Value , 'Vertical')
                    app.imgbwSDF = app.imgbwVerSDF;
                end
            end
        end

        % Button pushed function: ThickOrThinButton
        function ThickOrThinButtonPushed(app, event)
            %判断是否有img输入
            if isempty(app.originalImgbw)
                errordlg('Please load an image','Error');
            else
                app.DisplaylImgbw = changeBoundry( app.imgbwSDF,app.Slider.Value*app.StepEditField.Value);
                imshow(~app.DisplaylImgbw,'Parent',app.UI2);
                %更换标题
                if app.Slider.Value < 0
                    app.UI2.Title.String = 'Dilate';
                else
                    app.UI2.Title.String = 'Erode';
                end
            end
        end

        % Value changed function: DropDownOritation
        function DropDownOritationValueChanged(app, event)
%             msgbox(app.DropDownOritation.Value,'Path')
            if strcmp(app.DropDownOritation.Value , 'Full')
                app.imgbwSDF = app.imgbwNorSDF;
            elseif strcmp(app.DropDownOritation.Value , 'Horizontal')
                app.imgbwSDF = app.imgbwHorSDF;
            elseif strcmp(app.DropDownOritation.Value , 'Vertical')
                app.imgbwSDF = app.imgbwVerSDF;
            end

            
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
          %  app.EditField.Value = app.Slider.Value;

        end

        % Callback function
        function ShapeButtonPushed(app, event)
            
            if strcmp(app.DropDownShape.Value , 'Circle')

            elseif strcmp(app.DropDownShape.Value , 'Quadrilateral')
                
            end  

        end

        % Button pushed function: SETButton
        function SETButtonPushed(app, event)
            if isempty(app.originalImgbw)
                errordlg('Please load an image!','Error');
            else
                app.setBtnPushTimes = app.setBtnPushTimes + 1;
                if app.setBtnPushTimes == 1
                    app.set1Value = app.Slider.Value*app.StepEditField.Value;
                elseif app.setBtnPushTimes == 2
                    app.set2Value = app.Slider.Value*app.StepEditField.Value;
                else
                    app.set1Value = app.set2Value;
                    app.set2Value = app.Slider.Value*app.StepEditField.Value;
                end
            end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            if isempty(app.originalImgbw)
                errordlg('Please load an image!','Error');
            elseif app.set1Value == 0
                errordlg('Please set two imgs of different size','Error');
            elseif app.set1Value ~= 0 && app.set2Value == 0
                errordlg('Please set another img','Error');
            else
                if app.set1Value > app.set2Value
                    tmp = app.set2Value;
                    app.set2Value = app.set1Value;
                    app.set1Value = tmp;
                end
                %divide into 10 seperate value
                delta = abs(app.set1Value - app.set2Value)/9;
                [pwd,~,~] = fileparts(mfilename('fullpath')); 
                %oldPath = pwd;
                %cd('E:\code');
                savePath = [pwd,'/saveImg/'];
                for i=1:10
                    tmpBoudary = (i-1)*delta + app.set1Value;
                    tmpImg = ~changeBoundry( app.imgbwSDF, tmpBoudary);
                    imwrite(tmpImg,[savePath,num2str(i),'_',num2str(tmpBoudary),'.png']);
                end
                msgbox(['Images saved in ',savePath]);
            end            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ResizeThicknessUIFigure = uifigure;
            app.ResizeThicknessUIFigure.Name = 'Resize Thickness';
            app.LoadImageButton = uibutton(app.ResizeThicknessUIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.ThickOrThinButton = uibutton(app.ResizeThicknessUIFigure, 'push');
            app.ThickOrThinButton.ButtonPushedFcn = createCallbackFcn(app, @ThickOrThinButtonPushed, true);
            app.ThickOrThinButton.Text = 'ThickOrThin';
            app.DropDownOritation = uidropdown(app.ResizeThicknessUIFigure);
            app.DropDownOritation.Items = {'Full', 'Horizontal', 'Vertical'};
            app.DropDownOritation.ValueChangedFcn = createCallbackFcn(app, @DropDownOritationValueChanged, true);
            app.DropDownOritation.Value = 'Full';
            app.ImgPanel = uipanel(app.ResizeThicknessUIFigure);
            app.ImgPanel.Title = 'Img';
            app.UI1 = uiaxes(app.ImgPanel);
            title(app.UI1, 'Binary')
            app.UI2 = uiaxes(app.ImgPanel);
            title(app.UI2, 'Dilate')
            app.Slider = uislider(app.ResizeThicknessUIFigure);
            app.Slider.Limits = [-5 5];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.StepEditFieldLabel = uilabel(app.ResizeThicknessUIFigure);
            app.StepEditFieldLabel.Text = 'Step';
            app.StepEditField = uieditfield(app.ResizeThicknessUIFigure, 'numeric');
            app.SETButton = uibutton(app.ResizeThicknessUIFigure, 'push');
            app.SETButton.ButtonPushedFcn = createCallbackFcn(app, @SETButtonPushed, true);
            app.SETButton.Text = 'SET';
            app.ExportButton = uibutton(app.ResizeThicknessUIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
        end
    end

    methods (Access = public)

        % Construct app
        function app = chThickness

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ResizeThicknessUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ResizeThicknessUIFigure)
        end
    end
end